classdef InicioSesion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        btnRecuperar                    matlab.ui.control.Button
        ContrasenaEditField             matlab.ui.control.EditField
        IngresesuContraseaEditFieldLabel  matlab.ui.control.Label
        UsuarioEditField                matlab.ui.control.EditField
        IngresesuUsuarioEditFieldLabel  matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ErrorLabel                      matlab.ui.control.Label
        btnIniciarSesion                matlab.ui.control.Button
        SubTitleLabel                   matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        interfazInicial % Iniciar la Interfaz Inicial
        interfazRecuperacion %Interfaz de recuperacion de cuenta
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center')
        end

        % Button pushed function: btnIniciarSesion
        function btnIniciarSesionButtonPushed(app, event)
            usuario = app.UsuarioEditField.Value;
            contrasena = app.ContrasenaEditField.Value;
            
            % Conectar a la base de datos
            db = database('bd_Usuarios','root','');
            
            % Consultar la tabla de información
            selectquery = "SELECT * FROM informacion WHERE nombre_usuario = '" + string(usuario) + "' AND contrasena = '" + string(contrasena) + "'";
            resultados = select(db,selectquery);

            if ~isempty(resultados)
        
                % Obtener el tipo de usuario
                idtipo = resultados.idtipo;
                
                % Abrir la interfaz correspondiente
                app.interfazInicial = interfazIndex(app, usuario, idtipo);
        
            else
        
                % Mostrar un mensaje de error
                app.ErrorLabel.Position = [250,237 200 20];
                app.ErrorLabel.Text = 'Usuario o contraseña incorrectos';
            end
        
            % Cerrar la conexión a la base de datos
            db.close();
        end

        % Button pushed function: btnRecuperar
        function btnRecuperarButtonPushed(app, event)
            app.interfazInicial = Recuperacion(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Bienvenido al Sistema de Deteccion de Arritmias e Insuficiencias Cardiacas';
            app.SubTitleLabel = uilabel(app.UIFigure);
            app.SubTitleLabel.Text = 'Por favor inicie sesion';
            app.btnIniciarSesion = uibutton(app.UIFigure, 'push');
            app.btnIniciarSesion.ButtonPushedFcn = createCallbackFcn(app, @btnIniciarSesionButtonPushed, true);
            app.btnIniciarSesion.Text = 'Iniciar Sesion';
            app.ErrorLabel = uilabel(app.UIFigure);
            app.ErrorLabel.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'cenidet.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'upemor.png');
            app.IngresesuUsuarioEditFieldLabel = uilabel(app.UIFigure);
            app.IngresesuUsuarioEditFieldLabel.Text = 'Ingrese su Usuario';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.IngresesuContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.IngresesuContraseaEditFieldLabel.Text = 'Ingrese su Contraseña';
            app.ContrasenaEditField = uieditfield(app.UIFigure, 'text');
            app.btnRecuperar = uibutton(app.UIFigure, 'push');
            app.btnRecuperar.ButtonPushedFcn = createCallbackFcn(app, @btnRecuperarButtonPushed, true);
            app.btnRecuperar.Text = 'Recuperar Cuenta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InicioSesion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end