classdef IntDatosG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FrecuenciadeValidacionEditField  matlab.ui.control.NumericEditField
        FrecuenciadeValidacionEditFieldLabel  matlab.ui.control.Label
        CantidaddeBachEditField         matlab.ui.control.NumericEditField
        CantidaddeBachEditFieldLabel    matlab.ui.control.Label
        CantidaddeEpocasEditField       matlab.ui.control.NumericEditField
        CantidaddeEpocasEditFieldLabel  matlab.ui.control.Label
        TasadeAprendizajeInicialEditField  matlab.ui.control.NumericEditField
        TasadeAprendizajeInicialEditFieldLabel  matlab.ui.control.Label
        ActializarElementosButton       matlab.ui.control.Button
        CambiarHiperParametrosdeGoogLeNetLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        IntRedG % Interfaz Red Neuronal GoogLeNet
    end
    
    methods (Access = private)
        
        % Button pushed function: ActializarElementosButton
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, mxepch, batchSize, learnRate, fValidation)
            app.IntRedG = caller;

            app.TasadeAprendizajeInicialEditField.Value = learnRate;
            app.CantidaddeEpocasEditField.Value = mxepch;
            app.CantidaddeBachEditField.Value = batchSize;
            app.FrecuenciadeValidacionEditField.Value = fValidation;
            movegui(app.UIFigure, 'center')
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: ActializarElementosButton
        function ActializarElementosButtonPushed(app, event)
            ActualizarElementosGoogle(app.IntRedG, app.CantidaddeEpocasEditField.Value, app.CantidaddeBachEditField.Value, app.TasadeAprendizajeInicialEditField.Value, app.FrecuenciadeValidacionEditField.Value);

            delete(app)  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CambiarHiperParametrosdeGoogLeNetLabel = uilabel(app.UIFigure);
            app.CambiarHiperParametrosdeGoogLeNetLabel.Text = 'Cambiar HiperParametros de GoogLeNet';
            app.ActializarElementosButton = uibutton(app.UIFigure, 'push');
            app.ActializarElementosButton.ButtonPushedFcn = createCallbackFcn(app, @ActializarElementosButtonPushed, true);
            app.ActializarElementosButton.Text = 'Actializar Elementos';
            app.TasadeAprendizajeInicialEditFieldLabel = uilabel(app.UIFigure);
            app.TasadeAprendizajeInicialEditFieldLabel.Text = 'Tasa de Aprendizaje Inicial';
            app.TasadeAprendizajeInicialEditField = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddeEpocasEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddeEpocasEditFieldLabel.Text = 'Cantidad de Epocas';
            app.CantidaddeEpocasEditField = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddeBachEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddeBachEditFieldLabel.Text = 'Cantidad de Bach';
            app.CantidaddeBachEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciadeValidacionEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciadeValidacionEditFieldLabel.Text = 'Frecuencia de Validacion';
            app.FrecuenciadeValidacionEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IntDatosG(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end