classdef ManipularRedGApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CambiarParametrosdelaRedButton  matlab.ui.control.Button
        EntrenamientoyClasificacionconGoogLeNetLabel  matlab.ui.control.Label
        PrecisiondelentrenamientoEditField  matlab.ui.control.EditField
        PrecisiondelentrenamientoEditFieldLabel  matlab.ui.control.Label
        ImagenesdeValidacionEditField   matlab.ui.control.EditField
        ImagenesdeValidacionEditFieldLabel  matlab.ui.control.Label
        ImagenesdeEntrenamientoEditField  matlab.ui.control.EditField
        ImagenesdeEntrenamientoEditFieldLabel  matlab.ui.control.Label
        SubirArchivoECGEditField        matlab.ui.control.EditField
        SubirArchivoECGEditFieldLabel   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CrearyEntrenarRedNeuronaldeGoogLeNetButton  matlab.ui.control.Button
        CreaciondeEscalogramasyDivisiondeimagenesButton  matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ObservarDatosButton             matlab.ui.control.Button
        CrearDirectoriosButton          matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        BuscarArchivoButton             matlab.ui.control.Button
        ImportarArchivoButton           matlab.ui.control.Button
        PaneldePredicciones             matlab.ui.container.Panel
        ElegirimagenyacargadaDropDown   matlab.ui.control.DropDown
        ElegirimagenyacargadaDropDownLabel  matlab.ui.control.Label
        PrecisionEditField              matlab.ui.control.EditField
        PrecisionEditFieldLabel         matlab.ui.control.Label
        ClasePredichaEditField          matlab.ui.control.EditField
        ClasePredichaEditFieldLabel     matlab.ui.control.Label
        PredecirImagenButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        ECGData = [] %informacion
        Path % ruta del archivo
        Archivo %nombre del archivo
        DirPadre %Ruta de direccion raiz
        DirData %Ruta de informacion secundaria
        allimagenes % Todas las imagenes
        imgsEntrenamiento % Imagenes de entrenamiento
        imgsValidacion % Imagenes de validacion
        net % Red Neuronal de GoogLeNet
        lgrafo %Capas invocadas
        nuevaCapaDescartes %Capa para descartes
        numClases %numero de Clases a predecir
        nuevaCapaConec %Capa para conexiones
        nuevaCapaClasses %Capa para almacenar las clases
        opciones %Variable para entrenamiento
        entrenamientoGN %Variable para guardar el entrenamiento
        YPred %variable para almacenar  las predicciones
        accuracy %Variable para almacenar la precision
        indice %Variable para almacenar el indice de la imagen elegida
        accuracyVal %Presicion de Validacion
        YPredVal
        idTipo
        MiniBatchSize=15
        MaxEpochs=20
        InitialLearnRate=1e-4
        ValidationFrequency=10
        IntDatosGoogle
        Nombre
    end
    
    methods (Access = private)
        
        function CrearDirectoriosECG(~, ECGData,FolderPadre,FolderData)
            FolderRaiz = FolderPadre;
            FolderLocal = FolderData;
            if ~isfolder(FolderLocal)
                if ~isfolder(FolderLocal)
                    mkdir(fullfile(FolderRaiz,FolderLocal))
                    
                end
            end

            folderLabels = unique(ECGData.Labels);
            for i = 1:numel(folderLabels)
                mkdir(fullfile(FolderRaiz,FolderLocal,char(folderLabels(i))));
                
            end
            
        end

        function CreacionRGBdeTF(~,ECGData,FolderPadre,FolderHijo)
             % Convierte las variables FolderRaiz y FolderHijo a cadenas de texto
             existe = 0
            FolderRaiz = FolderPadre;
            FolderLocal = FolderHijo;
            % Asigna los valores de las variables FolderRaiz y FolderHijo a las variables imageRoot y imgFol
            imageRoot = fullfile(FolderRaiz,FolderLocal);
            data = ECGData.Data;
            labels = ECGData.Labels;

            [~,signalLength] = size(data);

            fb = cwtfilterbank(SignalLength=signalLength,VoicesPerOctave=12);
            r = size(data,1);
            files = dir(fullfile(imageRoot,"NSR"));
            for i = 1:length(files)
                if strcmp(files(i).name,"NSR_162.jpg")
                    existe = 1;
                end
            end
            if existe ~= 1
                for ii = 1:r
                    cfs = abs(fb.wt(data(ii,:)));
                    im = ind2rgb(round(rescale(cfs,0,255)),jet(128));
                    imgFol = fullfile(imageRoot,char(labels(ii)));
                    imArchivoNombre = char(labels(ii))+"_"+num2str(ii)+".jpg";
                    % Especifica el nombre del archivo de salida
                    filename = fullfile(imgFol,imArchivoNombre);
                    imwrite(imresize(im,[224 224]),filename);
                end
            end
        end
    end
    
    methods (Access = public)
        
        function ActualizarElementosGoogle(app, mxepch, batchSize, learnRate, fValidation)
            app.MaxEpochs = mxepch;
            app.MiniBatchSize = batchSize;
            app.InitialLearnRate = learnRate;
            app.ValidationFrequency = fValidation;

            app.CambiarParametrosdelaRedButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idTipo, Nombre)
            app.idTipo = idTipo;
            app.Nombre = Nombre;
            movegui(app.UIFigure, 'center')
            ActualizarElementosGoogle(app, app.MaxEpochs, app.MiniBatchSize, app.InitialLearnRate, app.ValidationFrequency)
        end

        % Button pushed function: ImportarArchivoButton
        function ImportarArchivoButtonPushed(app, event)
            if ~isempty(load(fullfile(app.Path,"ECGData.mat")))
                % Asigna el valor del archivo ECG a la variable ECGData
                ECGData2 = importdata(fullfile(string(app.Path),"ECGData.mat"));
                app.ECGData = ECGData2;
                % Verifica que la variable ECGData contenga los campos Labels y Data
                if ~isfield(app.ECGData, 'Labels') || ~isfield(app.ECGData, 'Data')
                    error('El archivo ECG no contiene los campos Labels y Data.');
                end
                app.Label.Position = [75 428 300 22];
               app.Label.Text = "Archivo correctamente Importado";
            else
               app.Label.Text = "Archivo no Importado";
            end
        end

        % Button pushed function: BuscarArchivoButton
        function BuscarArchivoButtonPushed(app, event)
            [filename, path] = uigetfile();
            app.SubirArchivoECGEditField.Value=filename;
            app.Archivo = filename;
            app.Path = path;
        end

        % Button pushed function: CrearDirectoriosButton
        function CrearDirectoriosButtonPushed(app, event)
            app.DirPadre = tempdir;
            app.DirData = "data";
            CrearDirectoriosECG(app,app.ECGData,app.DirPadre,app.DirData)
            app.Label.Text = "Carpetas correctamente creadas";
        end

        % Button pushed function: ObservarDatosButton
        function ObservarDatosButtonPushed(app, event)
            % Crea una nueva figura fuera de la aplicación
            fig = figure;

            % Agrega el código del plot a la figura
            folderLabels = unique(app.ECGData.Labels);
            for k=1:3
                ecgTipo = folderLabels{k};
                ind = find(ismember(app.ECGData.Labels,ecgTipo));
                subplot(3,1,k)
                plot(app.ECGData.Data(ind(1),1:1000));
                grid on
                title(ecgTipo)
            end

            % Muestra la figura
            fig.Visible = 'off';
            saveas(fig,'Graficas','jpg')
            app.Image.ImageSource = "Graficas.jpg";
            app.Image.Position = [310 243 313 229];
        end

        % Button pushed function: 
        % CreaciondeEscalogramasyDivisiondeimagenesButton
        function CreaciondeEscalogramasyDivisiondeimagenesButtonPushed(app, event)
            app.Label.Position = [10 428 700 22];
            app.Label.Text = "Esto Puede Tardar un momento, por favor espere";
            app.DirPadre = tempdir;
            app.DirData = "data";
            CreacionRGBdeTF(app,app.ECGData,app.DirPadre,app.DirData)
            app.Label.Text = "Listo, se a realizado los escalogramas correspondientes";
            app.allimagenes = imageDatastore(fullfile(app.DirPadre,app.DirData), "IncludeSubfolders",true, "LabelSource","foldernames");
            [app.imgsEntrenamiento,app.imgsValidacion] = splitEachLabel(app.allimagenes,0.8,"randomized");
            app.ImagenesdeEntrenamientoEditField.Value = num2str(numel(app.imgsEntrenamiento.Files));
            app.ImagenesdeValidacionEditField.Value =  num2str(numel(app.imgsValidacion.Files));
        end

        % Button pushed function: 
        % CrearyEntrenarRedNeuronaldeGoogLeNetButton
        function CrearyEntrenarRedNeuronaldeGoogLeNetButtonPushed(app, event)
            % Actualiza la posición y texto de la etiqueta para indicar que se está realizando un proceso.
            app.Label_2.Position = [44,120,500,22];
            app.Label_2.Text = "Esto puede tardar un poco";
            
            % Carga la red neuronal preentrenada GoogLeNet.
            app.net = googlenet;
            
            % Crea un grafo de capas a partir de la red GoogLeNet.
            app.lgrafo = layerGraph(app.net);
            
            % Reemplaza la capa de dropout por una nueva capa de dropout con la misma tasa de dropout.
            app.nuevaCapaDescartes = dropoutLayer(0.6, "Name", "nuevo_Descarte");
            app.lgrafo = replaceLayer(app.lgrafo, "pool5-drop_7x7_s1", app.nuevaCapaDescartes);
            
            % Obtiene el número de clases del conjunto de datos de entrenamiento.
            app.numClases = numel(categories(app.imgsEntrenamiento.Labels));
            
            % Crea una nueva capa totalmente conectada para la clasificación.
            app.nuevaCapaConec = fullyConnectedLayer(app.numClases, "Name", "new_fc", "WeightLearnRateFactor", 5, "BiasLearnRateFactor", 5);
            app.lgrafo = replaceLayer(app.lgrafo, "loss3-classifier", app.nuevaCapaConec);
            
            % Reemplaza la capa de salida por una nueva capa de salida de clasificación.
            app.nuevaCapaClasses = classificationLayer("Name", "nueva_SalidadeClase");
            app.lgrafo = replaceLayer(app.lgrafo, "output", app.nuevaCapaClasses);
            
            % Define las opciones de entrenamiento para la red GoogLeNet.
            app.opciones = trainingOptions("sgdm", ...
                MiniBatchSize=app.MiniBatchSize, ...
                MaxEpochs=app.MaxEpochs, ...
                InitialLearnRate=app.InitialLearnRate, ...
                ValidationData=app.imgsValidacion, ...
                ValidationFrequency=app.ValidationFrequency, ...
                Verbose=1, ...
                Plots="training-progress");
            
            % Entrena la red GoogLeNet con el conjunto de datos de entrenamiento y las opciones definidas.
            app.entrenamientoGN = trainNetwork(app.imgsEntrenamiento, app.lgrafo, app.opciones);
            
            % Realiza la predicción con la red entrenada en el conjunto de validación.
            [app.YPred, ~] = classify(app.entrenamientoGN, app.imgsValidacion);
            
            % Calcula la precisión del modelo.
            app.accuracy = mean(app.YPred == app.imgsValidacion.Labels);
            
            % Establece la precisión de entrenamiento en el campo correspondiente.
            app.PrecisiondelentrenamientoEditField.Value = num2str(100 * app.accuracy) + "%";
        
            % Configuración para enviar correo electrónico.
            mail = 'rengerrojo@gmail.com';
            password = 'dsuybsayemcmfpmm';
            server = 'smtp.gmail.com';
        
            setpref('Internet', 'E_mail', mail);
            setpref('Internet', 'SMTP_Server', server);
            setpref('Internet', 'SMTP_Username', mail);
            setpref('Internet', 'SMTP_Password', password);
            setpref('Internet', 'SMTP_Auth', 'true');
        
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth', 'true');
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port', '465');
            
            % Conexión a la base de datos
            db = database('bd_Usuarios', 'root', '');
            % Consulta a la tabla de información
            selectquery = "SELECT * FROM informacion WHERE nombre_usuario = '" + string(app.Nombre) + "'";
            resultados = select(db, selectquery);
        
            % Verificar si hay resultados en la consulta
            if ~isempty(resultados)
                % Obtener valores de parámetros de entrenamiento y precisión
                MiniBatchSizeCorreo = app.MiniBatchSize;
                MaxEpochsCorreo = app.MaxEpochs;
                InitialLearnRateCorreo = app.InitialLearnRate;
                PrecisionCorreo = app.PrecisiondelentrenamientoEditField.Value;
                FrecuenciaCorreo = app.ValidationFrequency;
        
                % Crear el mensaje de correo con los parámetros y precisión
                mensaje = sprintf('Parámetros de entrenamiento Red Neuronal GoogLeNet:\nMiniBatchSize: %d\nMaxEpochs: %d\nInitialLearnRate: %.4f\nFrecuencia de validación: %d\nPrecisión: %s', ...
                    MiniBatchSizeCorreo, MaxEpochsCorreo, InitialLearnRateCorreo, FrecuenciaCorreo, PrecisionCorreo);
        
                % Enviar el correo
                sendmail(resultados.email, 'Reporte de entrenamiento', mensaje);
                app.Label_2.Position = [135,120,500,22];
                app.Label_2.Text = "Reporte Enviado!";
            end
        end

        % Drop down opening function: ElegirimagenyacargadaDropDown
        function ElegirimagenyacargadaDropDownOpening(app, event)
            % Obtiene los nombres de los archivos de las imágenes de validación
            filenames = app.imgsValidacion.Files;

            % Crea los items del desplegable
            app.ElegirimagenyacargadaDropDown.Items = {};
            for indice2 = 1:numel(filenames)
                app.ElegirimagenyacargadaDropDown.Items(indice2) = filenames(indice2);
                app.indice = indice2;
            end
        end

        % Button pushed function: PredecirImagenButton
        function PredecirImagenButtonPushed(app, event)
            % Copia la predicción actual a una variable local para fines de referencia.
            app.YPredVal = app.YPred;
            
            % Obtiene el nombre del archivo seleccionado en el menú desplegable.
            filename = app.ElegirimagenyacargadaDropDown.Value;
            
            % Encuentra el índice correspondiente al nombre del archivo seleccionado.
            app.indice = find(strcmp(app.imgsValidacion.Files, filename));
            
            % Lee la imagen de validación correspondiente al índice seleccionado.
            imagen_val = readimage(app.imgsValidacion, app.indice);
            
            % Obtiene la etiqueta real de la imagen de validación.
            etiqueta_real = app.imgsValidacion.Labels(app.indice);
            
            % Realiza la predicción de la imagen utilizando la red neuronal previamente entrenada.
            app.YPredVal = classify(app.entrenamientoGN, imagen_val);
            
            % Calcula la precisión de la predicción.
            app.accuracyVal = mean(app.YPredVal == etiqueta_real);
            
            % Establece la clase predicha según el resultado de la predicción.
            if char(app.YPredVal) == "ARR"
                app.ClasePredichaEditField.Value = "Puede padecer arritmia";
            elseif char(app.YPredVal) == "NSR"
                app.ClasePredichaEditField.Value = "Estado Normal";
            elseif char(app.YPredVal) == "CHF"
                app.ClasePredichaEditField.Value = "Puede padecer Insuficiencia Cardiaca";
            end
            
            % Convierte el valor de precisión a un formato legible y lo muestra en el campo correspondiente.
            precisionEditFieldValue = string(100 * app.accuracyVal);
            app.PrecisionEditField.Value = precisionEditFieldValue + "%";
        end

        % Button pushed function: CambiarParametrosdelaRedButton
        function CambiarParametrosdelaRedButtonPushed(app, event)
            app.CambiarParametrosdelaRedButton.Enable = "off";
        
            % Call dialog box with input values
            app.IntDatosGoogle = IntDatosG(app,app.MaxEpochs,app.MiniBatchSize,app.InitialLearnRate,app.ValidationFrequency);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PaneldePredicciones = uipanel(app.UIFigure);
            app.PaneldePredicciones.Title = 'Panel de Predicciones';
            app.PredecirImagenButton = uibutton(app.PaneldePredicciones, 'push');
            app.PredecirImagenButton.ButtonPushedFcn = createCallbackFcn(app, @PredecirImagenButtonPushed, true);
            app.PredecirImagenButton.Text = 'Predecir Imagen';
            app.ClasePredichaEditFieldLabel = uilabel(app.PaneldePredicciones);
            app.ClasePredichaEditFieldLabel.Text = 'Clase Predicha';
            app.ClasePredichaEditField = uieditfield(app.PaneldePredicciones, 'text');
            app.PrecisionEditFieldLabel = uilabel(app.PaneldePredicciones);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.PaneldePredicciones, 'text');
            app.ElegirimagenyacargadaDropDownLabel = uilabel(app.PaneldePredicciones);
            app.ElegirimagenyacargadaDropDownLabel.Text = 'Elegir imagen ya cargada';
            app.ElegirimagenyacargadaDropDown = uidropdown(app.PaneldePredicciones);
            app.ElegirimagenyacargadaDropDown.Items = {};
            app.ElegirimagenyacargadaDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ElegirimagenyacargadaDropDownOpening, true);
            app.ElegirimagenyacargadaDropDown.Value = {};
            app.ImportarArchivoButton = uibutton(app.UIFigure, 'push');
            app.ImportarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarArchivoButtonPushed, true);
            app.ImportarArchivoButton.Text = 'Importar Archivo';
            app.BuscarArchivoButton = uibutton(app.UIFigure, 'push');
            app.BuscarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarArchivoButtonPushed, true);
            app.BuscarArchivoButton.Text = 'Buscar Archivo';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CrearDirectoriosButton = uibutton(app.UIFigure, 'push');
            app.CrearDirectoriosButton.ButtonPushedFcn = createCallbackFcn(app, @CrearDirectoriosButtonPushed, true);
            app.CrearDirectoriosButton.Text = 'Crear Directorios';
            app.ObservarDatosButton = uibutton(app.UIFigure, 'push');
            app.ObservarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @ObservarDatosButtonPushed, true);
            app.ObservarDatosButton.Text = 'Observar Datos';
            app.Image = uiimage(app.UIFigure);
            app.CreaciondeEscalogramasyDivisiondeimagenesButton = uibutton(app.UIFigure, 'push');
            app.CreaciondeEscalogramasyDivisiondeimagenesButton.ButtonPushedFcn = createCallbackFcn(app, @CreaciondeEscalogramasyDivisiondeimagenesButtonPushed, true);
            app.CreaciondeEscalogramasyDivisiondeimagenesButton.Text = 'Creacion de Escalogramas y Division de imagenes';
            app.CrearyEntrenarRedNeuronaldeGoogLeNetButton = uibutton(app.UIFigure, 'push');
            app.CrearyEntrenarRedNeuronaldeGoogLeNetButton.ButtonPushedFcn = createCallbackFcn(app, @CrearyEntrenarRedNeuronaldeGoogLeNetButtonPushed, true);
            app.CrearyEntrenarRedNeuronaldeGoogLeNetButton.Text = 'Crear y Entrenar Red Neuronal de GoogLe Net';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.SubirArchivoECGEditFieldLabel = uilabel(app.UIFigure);
            app.SubirArchivoECGEditFieldLabel.Text = 'Subir Archivo ECG';
            app.SubirArchivoECGEditField = uieditfield(app.UIFigure, 'text');
            app.ImagenesdeEntrenamientoEditFieldLabel = uilabel(app.UIFigure);
            app.ImagenesdeEntrenamientoEditFieldLabel.Text = 'Imagenes de Entrenamiento';
            app.ImagenesdeEntrenamientoEditField = uieditfield(app.UIFigure, 'text');
            app.ImagenesdeValidacionEditFieldLabel = uilabel(app.UIFigure);
            app.ImagenesdeValidacionEditFieldLabel.Text = 'Imagenes de Validacion';
            app.ImagenesdeValidacionEditField = uieditfield(app.UIFigure, 'text');
            app.PrecisiondelentrenamientoEditFieldLabel = uilabel(app.UIFigure);
            app.PrecisiondelentrenamientoEditFieldLabel.Text = 'Precision del entrenamiento';
            app.PrecisiondelentrenamientoEditField = uieditfield(app.UIFigure, 'text');
            app.EntrenamientoyClasificacionconGoogLeNetLabel = uilabel(app.UIFigure);
            app.EntrenamientoyClasificacionconGoogLeNetLabel.Text = 'Entrenamiento y Clasificacion con GoogLeNet';
            app.CambiarParametrosdelaRedButton = uibutton(app.UIFigure, 'push');
            app.CambiarParametrosdelaRedButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarParametrosdelaRedButtonPushed, true);
            app.CambiarParametrosdelaRedButton.Text = 'Cambiar Parametros de la Red';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipularRedGApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end