classdef ManipularRedSApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CambiarParametrosdelaRedButton  matlab.ui.control.Button
        EntrenamientoyClasificacionconSqueezeNetLabel  matlab.ui.control.Label
        PrecisiondelentrenamientoEditField  matlab.ui.control.EditField
        PrecisiondelentrenamientoEditFieldLabel  matlab.ui.control.Label
        ImagenesdeValidacionEditField   matlab.ui.control.EditField
        ImagenesdeValidacionEditFieldLabel  matlab.ui.control.Label
        ImagenesdeEntrenamientoEditField  matlab.ui.control.EditField
        ImagenesdeEntrenamientoEditFieldLabel  matlab.ui.control.Label
        SubirArchivoECGEditField        matlab.ui.control.EditField
        SubirArchivoECGEditFieldLabel   matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        CrearyEntrenarRedNeuronaldeSqueezeNetButton  matlab.ui.control.Button
        CreaciondeEscalogramasyDivisiondeimagenesButton  matlab.ui.control.Button
        PaneldePredicciones             matlab.ui.container.Panel
        ElegirimagenyacargadaDropDown   matlab.ui.control.DropDown
        ElegirimagenyacargadaDropDownLabel  matlab.ui.control.Label
        PrecisionEditField              matlab.ui.control.EditField
        PrecisionEditFieldLabel         matlab.ui.control.Label
        ClasePredichaEditField          matlab.ui.control.EditField
        ClasePredichaEditFieldLabel     matlab.ui.control.Label
        PredecirImagenButton            matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        ObservarDatosButton             matlab.ui.control.Button
        CrearDirectoriosButton          matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        BuscarArchivoButton             matlab.ui.control.Button
        ImportarArchivoButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        ECGData = [] %informacion
        Path % ruta del archivo
        Archivo %nombre del archivo
        DirPadre %Ruta de direccion raiz
        DirData %Ruta de informacion secundaria
        allimagenes % Todas las imagenes
        imgsEntrenamientoSqz % Imagenes de entrenamiento
        imgsValidacionSqz % Imagenes de validacion
        sqz % Red Neuronal de SqueezeNet
        lgrafoSqz %Capas invocadas
        nuevaCapaDescartesSqz %Capa para descartes
        nuevaCapaAprendizajeSqz %Capa para aprendizaje
        numClasesSqz %numero de Clases a predecir
        tmpCapa % Capa Temporal
        nuevaCapaClassesSQZ %Capa para almacenar las clases
        augimgsEntrena %Flujo de datos de entrenamiento
        augimgsValidacion %Flujo de datos de validacion
        ilrSqz = 3e-4
        miniBatchSizeSqz = 10
        maxEpochsSqz = 15
        valFreqSqz
        optsSqz
        entrenamientoSN
        accuracySqz
        YPredSqz
        probsSqz
        YPredValSqz
        indiceSqz
        accuracyValSqz
        idTipo %Variable para guardar el tipo de usuario
        IntDatos % Interfaz Con Datos A cambiar
        Nombre %Variable para guardar Nombre del usaurio
    end
    
    methods (Access = private)
        
        % Esta función crea directorios para almacenar datos ECG.
        function CrearDirectoriosECG(~, ECGData, FolderPadre, FolderData)
            % Asigna las variables del directorio raíz y local.
            FolderRaiz = FolderPadre;
            FolderLocal = FolderData;
            
            % Verifica si el directorio local no existe y lo crea.
            if ~isfolder(FolderLocal)
                mkdir(fullfile(FolderRaiz, FolderLocal));
            end
        
            % Obtiene etiquetas únicas de los datos ECG y crea carpetas para cada etiqueta.
            folderLabels = unique(ECGData.Labels);
            for i = 1:numel(folderLabels)
                mkdir(fullfile(FolderRaiz, FolderLocal, char(folderLabels(i))));
            end
        end
        
        % Esta función crea imágenes RGB de la transformada continua de Wavelet de los datos ECG.
        function CreacionRGBdeTF(~, ECGData, FolderPadre, FolderHijo)
            % Inicializa variables y obtiene rutas de directorios.
            existe = 0;
            FolderRaiz = FolderPadre;
            FolderLocal = FolderHijo;
            imageRoot = fullfile(FolderRaiz, FolderLocal);
            data = ECGData.Data;
            labels = ECGData.Labels;
        
            % Obtiene la longitud de la señal.
            [~, signalLength] = size(data);
        
            % Configura el banco de filtros continuos de Wavelet.
            fb = cwtfilterbank('SignalLength', signalLength, 'VoicesPerOctave', 12);
            r = size(data, 1);
        
            % Verifica la existencia de un archivo específico y lo establece en existe.
            files = dir(fullfile(imageRoot, "NSR"));
            for i = 1:length(files)
                if strcmp(files(i).name, "NSR_162.jpg")
                    existe = 1;
                end
            end
        
            % Si el archivo no existe, crea imágenes RGB para cada dato ECG.
            if existe ~= 1
                for ii = 1:r
                    % Calcula la transformada continua de Wavelet y crea la imagen RGB.
                    cfs = abs(fb.wt(data(ii, :)));
                    im = ind2rgb(round(rescale(cfs, 0, 255)), jet(128));
                    
                    % Establece la ruta del directorio para cada etiqueta.
                    imgFol = fullfile(imageRoot, char(labels(ii)));
                    
                    % Establece el nombre del archivo de salida.
                    imArchivoNombre = char(labels(ii)) + "_" + num2str(ii) + ".jpg";
                    filename = fullfile(imgFol, imArchivoNombre);
                    
                    % Guarda la imagen redimensionada.
                    imwrite(imresize(im, [224 224]), filename);
                end
            end
        end
    end
    
    methods (Access = public)
        
        % Esta función actualiza los elementos de la aplicación con los parámetros de entrenamiento.
        function ActualizarElementos(app, mxepch, batchSize, learnRate)
            % Establece el número máximo de épocas de entrenamiento en la aplicación.
            app.maxEpochsSqz = mxepch;
            
            % Establece el tamaño del lote miniatura para el entrenamiento en la aplicación.
            app.miniBatchSizeSqz = batchSize;
            
            % Establece la tasa de aprendizaje inicial para el entrenamiento en la aplicación.
            app.ilrSqz = learnRate;
        
            % Habilita el botón para cambiar los parámetros de la red en la interfaz de la aplicación.
            app.CambiarParametrosdelaRedButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idTipo, Nombre)
            app.idTipo = idTipo;
            app.Nombre = Nombre;
            movegui(app.UIFigure, 'center')
            ActualizarElementos(app, app.maxEpochsSqz, app.miniBatchSizeSqz, app.ilrSqz)
        end

        % Button pushed function: ImportarArchivoButton
        function ImportarArchivoButtonPushed(app, event)
            if ~isempty(load(fullfile(app.Path,"ECGData.mat")))
                % Asigna el valor del archivo ECG a la variable ECGData
                ECGData2 = importdata(fullfile(string(app.Path),"ECGData.mat"));
                app.ECGData = ECGData2;
                % Verifica que la variable ECGData contenga los campos Labels y Data
                if ~isfield(app.ECGData, 'Labels') || ~isfield(app.ECGData, 'Data')
                    error('El archivo ECG no contiene los campos Labels y Data.');
                end
                app.Label.Position = [75 428 300 22];
               app.Label.Text = "Archivo correctamente Importado";
            else
               app.Label.Text = "Archivo no Importado";
            end
        end

        % Button pushed function: BuscarArchivoButton
        function BuscarArchivoButtonPushed(app, event)
            % Abre el cuadro de diálogo para seleccionar un archivo.
            [filename, path] = uigetfile();
            
            % Asigna el nombre del archivo seleccionado al campo de edición correspondiente en la aplicación.
            app.SubirArchivoECGEditField.Value = filename;
            
            % Guarda el nombre del archivo y la ruta en las propiedades de la aplicación.
            app.Archivo = filename;
            app.Path = path;
        end

        % Button pushed function: CrearDirectoriosButton
        function CrearDirectoriosButtonPushed(app, event)
            app.DirPadre = tempdir;
            app.DirData = "data";
            CrearDirectoriosECG(app,app.ECGData,app.DirPadre,app.DirData)
            app.Label.Text = "Carpetas correctamente creadas";
        end

        % Button pushed function: ObservarDatosButton
        function ObservarDatosButtonPushed(app, event)
            % Crea una nueva figura fuera de la aplicación
            fig = figure;

            % Agrega el código del plot a la figura
            folderLabels = unique(app.ECGData.Labels);
            for k=1:3
                ecgTipo = folderLabels{k};
                ind = find(ismember(app.ECGData.Labels,ecgTipo));
                subplot(3,1,k)
                plot(app.ECGData.Data(ind(1),1:1000));
                grid on
                title(ecgTipo)
            end

            % Muestra la figura
            fig.Visible = 'off';
            saveas(fig,'Graficas','jpg')
            app.Image.ImageSource = "Graficas.jpg";
            app.Image.Position = [310 243 313 229];
        end

        % Button pushed function: 
        % CreaciondeEscalogramasyDivisiondeimagenesButton
        function CreaciondeEscalogramasyDivisiondeimagenesButtonPushed(app, event)
            % Actualiza la posición y texto de la etiqueta para indicar que se está realizando un proceso.
            app.Label.Position = [10 428 700 22];
            app.Label.Text = "Esto Puede Tardar un momento, por favor espere";
            
            % Establece los directorios padre y de datos temporales.
            app.DirPadre = tempdir;
            app.DirData = "data";
            
            % Crea los escalogramas de imágenes utilizando la función CreacionRGBdeTF.
            CreacionRGBdeTF(app, app.ECGData, app.DirPadre, app.DirData)
            
            % Actualiza la etiqueta para indicar que se han creado los escalogramas correspondientes.
            app.Label.Text = "Listo, se ha realizado los escalogramas correspondientes";
            
            % Crea un datastore de imágenes que incluye subcarpetas y utiliza las etiquetas de las carpetas.
            app.allimagenes = imageDatastore(fullfile(app.DirPadre, app.DirData), "IncludeSubfolders", true, "LabelSource", "foldernames");
            
            % Divide las imágenes en conjuntos de entrenamiento y validación (80% y 20% respectivamente).
            [app.imgsEntrenamientoSqz, app.imgsValidacionSqz] = splitEachLabel(app.allimagenes, 0.8, "randomized");
            
            % Actualiza los campos de edición con el número de imágenes de entrenamiento y validación.
            app.ImagenesdeEntrenamientoEditField.Value = num2str(numel(app.imgsEntrenamientoSqz.Files));
            app.ImagenesdeValidacionEditField.Value = num2str(numel(app.imgsValidacionSqz.Files));
        end

        % Button pushed function: 
        % CrearyEntrenarRedNeuronaldeSqueezeNetButton
        function CrearyEntrenarRedNeuronaldeSqueezeNetButtonPushed(app, event)
            % Actualiza la posición y texto de la etiqueta para indicar que el proceso está en curso.
            app.Label_2.Position = [44, 120, 500, 22];
            app.Label_2.Text = "Esto puede tardar un poco";
            
            % Crea la red neuronal SqueezeNet.
            app.sqz = squeezenet;
            app.lgrafoSqz = layerGraph(app.sqz);
            
            % Modifica la arquitectura de la red para adaptarla al problema.
            app.tmpCapa = app.lgrafoSqz.Layers(end-5);
            app.nuevaCapaDescartesSqz = dropoutLayer(0.6, "Name", "nuevo_Descarte");
            app.lgrafoSqz = replaceLayer(app.lgrafoSqz, app.tmpCapa.Name, app.nuevaCapaDescartesSqz);
            
            app.numClasesSqz = numel(categories(app.imgsEntrenamientoSqz.Labels));
            app.tmpCapa = app.lgrafoSqz.Layers(end-4);
            app.nuevaCapaAprendizajeSqz = convolution2dLayer(1, app.numClasesSqz, "Name", "nueva_conv", "WeightLearnRateFactor", 10, "BiasLearnRateFactor", 10);
            app.lgrafoSqz = replaceLayer(app.lgrafoSqz, app.tmpCapa.Name, app.nuevaCapaAprendizajeSqz);
            
            app.tmpCapa = app.lgrafoSqz.Layers(end);
            app.nuevaCapaClassesSQZ = classificationLayer("Name", "nueva_SalidadeClase");
            app.lgrafoSqz = replaceLayer(app.lgrafoSqz, app.tmpCapa.Name, app.nuevaCapaClassesSQZ);
            
            % Prepara los datos de entrenamiento y validación.
            app.augimgsEntrena = augmentedImageDatastore([227 227], app.imgsEntrenamientoSqz);
            app.augimgsValidacion = augmentedImageDatastore([227 227], app.imgsValidacionSqz);
            
            app.valFreqSqz = floor(numel(app.augimgsEntrena.Files) / app.miniBatchSizeSqz);
            
            % Define las opciones de entrenamiento.
            app.optsSqz = trainingOptions("sgdm", ...
                MiniBatchSize = app.miniBatchSizeSqz, ...
                MaxEpochs = app.maxEpochsSqz, ...
                InitialLearnRate = app.ilrSqz, ...
                ValidationData = app.augimgsValidacion, ...
                ValidationFrequency = app.valFreqSqz, ...
                Verbose = 1, ...
                Plots = "training-progress");
            
            % Entrena la red neuronal.
            app.entrenamientoSN = trainNetwork(app.augimgsEntrena, app.lgrafoSqz, app.optsSqz);
            
            % Clasifica las imágenes de validación y calcula la precisión.
            [app.YPredSqz, app.probsSqz] = classify(app.entrenamientoSN, app.augimgsValidacion);
            app.accuracySqz = mean(app.YPredSqz == app.imgsValidacionSqz.Labels);
            
            % Actualiza el campo de edición con la precisión del entrenamiento.
            app.PrecisiondelentrenamientoEditField.Value = num2str(100 * app.accuracySqz) + "%";
        
            % Configura el envío de correos electrónicos.
            mail = 'rengerrojo@gmail.com';
            password = 'dsuybsayemcmfpmm';
            server = 'smtp.gmail.com';
        
            setpref('Internet', 'E_mail', mail);
            setpref('Internet', 'SMTP_Server', server);
            setpref('Internet', 'SMTP_Username', mail);
            setpref('Internet', 'SMTP_Password', password);
            setpref('Internet', 'SMTP_Auth', 'true');
        
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth', 'true');
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port', '465');
        
            % Conecta a la base de datos
            db = database('bd_Usuarios', 'root', '');
            
            % Consulta la tabla de información
            selectquery = "SELECT * FROM informacion WHERE nombre_usuario = '" + string(app.Nombre) + "'";
            resultados = select(db, selectquery);
        
            % Verifica si el query contiene información
            if ~isempty(resultados)
                % Obtiene los valores de los parámetros de entrenamiento y precisión.
                MiniBatchSizeCorreo = app.miniBatchSizeSqz;
                MaxEpochsCorreo = app.maxEpochsSqz;
                InitialLearnRateCorreo = app.ilrSqz;
                PrecisionCorreo = app.PrecisiondelentrenamientoEditField.Value;
        
                % Crea el mensaje de correo con los parámetros y la precisión.
                mensaje = sprintf('Parámetros de entrenamiento Red Neuronal SqueezeNet:\nMiniBatchSize: %d\nMaxEpochs: %d\nInitialLearnRate: %.4f\nPrecision: %s', ...
                    MiniBatchSizeCorreo, MaxEpochsCorreo, InitialLearnRateCorreo, PrecisionCorreo);
        
                % Envía el correo.
                sendmail(resultados.email, 'Reporte de entrenamiento', mensaje);
                app.Label_2.Position = [135, 120, 500, 22];
                app.Label_2.Text = "Reporte Enviado!";
            end
        end

        % Drop down opening function: ElegirimagenyacargadaDropDown
        function ElegirimagenyacargadaDropDownOpening(app, event)
            % Obtiene los nombres de los archivos de las imágenes de validación
            filenames = app.imgsValidacionSqz.Files;

            % Crea los items del desplegable
            app.ElegirimagenyacargadaDropDown.Items = {};
            for indice3 = 1:numel(filenames)
                app.ElegirimagenyacargadaDropDown.Items(indice3) = filenames(indice3);
                app.indiceSqz = indice3;
            end
        end

        % Button pushed function: PredecirImagenButton
        function PredecirImagenButtonPushed(app, event)
            % Copia la predicción actual a una variable local para fines de referencia.
            app.YPredValSqz = app.YPredSqz;
            
            % Obtiene el nombre del archivo seleccionado en el menú desplegable.
            filenameSqz = app.ElegirimagenyacargadaDropDown.Value;
            
            % Encuentra el índice correspondiente al nombre del archivo seleccionado.
            app.indiceSqz = find(strcmp(app.imgsValidacionSqz.Files, filenameSqz));
            
            % Lee la imagen de validación correspondiente al índice seleccionado.
            imagen_valSqz = readimage(app.imgsValidacionSqz, app.indiceSqz);
            
            % Obtiene la etiqueta real de la imagen de validación.
            etiqueta_realSqz = app.imgsValidacionSqz.Labels(app.indiceSqz);
            
            % Redimensiona la imagen de validación a las dimensiones requeridas.
            imagen_valSqz = imresize(imagen_valSqz, [227 227]);
            
            % Realiza la predicción de la imagen utilizando la red neuronal previamente entrenada.
            app.YPredValSqz = classify(app.entrenamientoSN, imagen_valSqz);
            
            % Calcula la precisión de la predicción.
            app.accuracyValSqz = mean(app.YPredValSqz == etiqueta_realSqz);
            
            % Establece la clase predicha según el resultado de la predicción.
            if char(app.YPredValSqz) == "ARR"
                app.ClasePredichaEditField.Value = "Puede padecer arritmia";
            elseif char(app.YPredValSqz) == "NSR"
                app.ClasePredichaEditField.Value = "Estado Normal";
            elseif char(app.YPredValSqz) == "CHF"
                app.ClasePredichaEditField.Value = "Puede padecer Insuficiencia Cardiaca";
            end
            
            % Convierte el valor de precisión a un formato legible y lo muestra en el campo correspondiente.
            precisionEditFieldValue = string(100 * app.accuracyValSqz);
            app.PrecisionEditField.Value = precisionEditFieldValue + "%";
        end

        % Button pushed function: CambiarParametrosdelaRedButton
        function CambiarParametrosdelaRedButtonPushed(app, event)
            app.CambiarParametrosdelaRedButton.Enable = "off";
        
            % Call dialog box with input values
            app.IntDatos = interfaceDatosS(app,app.maxEpochsSqz,app.miniBatchSizeSqz,app.ilrSqz);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportarArchivoButton = uibutton(app.UIFigure, 'push');
            app.ImportarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarArchivoButtonPushed, true);
            app.ImportarArchivoButton.Text = 'Importar Archivo';
            app.BuscarArchivoButton = uibutton(app.UIFigure, 'push');
            app.BuscarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarArchivoButtonPushed, true);
            app.BuscarArchivoButton.Text = 'Buscar Archivo';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CrearDirectoriosButton = uibutton(app.UIFigure, 'push');
            app.CrearDirectoriosButton.ButtonPushedFcn = createCallbackFcn(app, @CrearDirectoriosButtonPushed, true);
            app.CrearDirectoriosButton.Text = 'Crear Directorios';
            app.ObservarDatosButton = uibutton(app.UIFigure, 'push');
            app.ObservarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @ObservarDatosButtonPushed, true);
            app.ObservarDatosButton.Text = 'Observar Datos';
            app.Image = uiimage(app.UIFigure);
            app.PaneldePredicciones = uipanel(app.UIFigure);
            app.PaneldePredicciones.Title = 'Panel de Predicciones';
            app.PredecirImagenButton = uibutton(app.PaneldePredicciones, 'push');
            app.PredecirImagenButton.ButtonPushedFcn = createCallbackFcn(app, @PredecirImagenButtonPushed, true);
            app.PredecirImagenButton.Text = 'Predecir Imagen';
            app.ClasePredichaEditFieldLabel = uilabel(app.PaneldePredicciones);
            app.ClasePredichaEditFieldLabel.Text = 'Clase Predicha';
            app.ClasePredichaEditField = uieditfield(app.PaneldePredicciones, 'text');
            app.PrecisionEditFieldLabel = uilabel(app.PaneldePredicciones);
            app.PrecisionEditFieldLabel.Text = 'Precision';
            app.PrecisionEditField = uieditfield(app.PaneldePredicciones, 'text');
            app.ElegirimagenyacargadaDropDownLabel = uilabel(app.PaneldePredicciones);
            app.ElegirimagenyacargadaDropDownLabel.Text = 'Elegir imagen ya cargada';
            app.ElegirimagenyacargadaDropDown = uidropdown(app.PaneldePredicciones);
            app.ElegirimagenyacargadaDropDown.Items = {};
            app.ElegirimagenyacargadaDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ElegirimagenyacargadaDropDownOpening, true);
            app.ElegirimagenyacargadaDropDown.Value = {};
            app.CreaciondeEscalogramasyDivisiondeimagenesButton = uibutton(app.UIFigure, 'push');
            app.CreaciondeEscalogramasyDivisiondeimagenesButton.ButtonPushedFcn = createCallbackFcn(app, @CreaciondeEscalogramasyDivisiondeimagenesButtonPushed, true);
            app.CreaciondeEscalogramasyDivisiondeimagenesButton.Text = 'Creacion de Escalogramas y Division de imagenes';
            app.CrearyEntrenarRedNeuronaldeSqueezeNetButton = uibutton(app.UIFigure, 'push');
            app.CrearyEntrenarRedNeuronaldeSqueezeNetButton.ButtonPushedFcn = createCallbackFcn(app, @CrearyEntrenarRedNeuronaldeSqueezeNetButtonPushed, true);
            app.CrearyEntrenarRedNeuronaldeSqueezeNetButton.Text = 'Crear y Entrenar Red Neuronal de SqueezeNet';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.SubirArchivoECGEditFieldLabel = uilabel(app.UIFigure);
            app.SubirArchivoECGEditFieldLabel.Text = 'Subir Archivo ECG';
            app.SubirArchivoECGEditField = uieditfield(app.UIFigure, 'text');
            app.ImagenesdeEntrenamientoEditFieldLabel = uilabel(app.UIFigure);
            app.ImagenesdeEntrenamientoEditFieldLabel.Text = 'Imagenes de Entrenamiento';
            app.ImagenesdeEntrenamientoEditField = uieditfield(app.UIFigure, 'text');
            app.ImagenesdeValidacionEditFieldLabel = uilabel(app.UIFigure);
            app.ImagenesdeValidacionEditFieldLabel.Text = 'Imagenes de Validacion';
            app.ImagenesdeValidacionEditField = uieditfield(app.UIFigure, 'text');
            app.PrecisiondelentrenamientoEditFieldLabel = uilabel(app.UIFigure);
            app.PrecisiondelentrenamientoEditFieldLabel.Text = 'Precision del entrenamiento';
            app.PrecisiondelentrenamientoEditField = uieditfield(app.UIFigure, 'text');
            app.EntrenamientoyClasificacionconSqueezeNetLabel = uilabel(app.UIFigure);
            app.EntrenamientoyClasificacionconSqueezeNetLabel.Text = 'Entrenamiento y Clasificacion con SqueezeNet';
            app.CambiarParametrosdelaRedButton = uibutton(app.UIFigure, 'push');
            app.CambiarParametrosdelaRedButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarParametrosdelaRedButtonPushed, true);
            app.CambiarParametrosdelaRedButton.Text = 'Cambiar Parametros de la Red';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipularRedSApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end