classdef ManipularRegistros < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CreararchivoMatdeDatosButton    matlab.ui.control.Button
        CrearArchivoMatGeneralButton    matlab.ui.control.Button
        EliminarButton                  matlab.ui.control.Button
        ElegirPathaeliminarDropDown     matlab.ui.control.DropDown
        ElegirPathaeliminarDropDownLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        AgregaralPathButton             matlab.ui.control.Button
        EstoPuedeTardarLabel            matlab.ui.control.Label
        BaseDeDatosDropDown             matlab.ui.control.DropDown
        BaseDeDatosDropDownLabel        matlab.ui.control.Label
        Descargar                       matlab.ui.control.Button
        ManipulardatosdePhysionetLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        data % Description
        archivos % Description
        files
        nombres = [""] % Lista que almacena los nombres
        indice = 0;
        indiceDrop = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center')
        end

        % Button pushed function: Descargar
        function DescargarButtonPushed(app, event)
            pyrunfile('ObtenerBases.py')
            app.EstoPuedeTardarLabel.Text = "Listo!";
        end

        % Button pushed function: CreararchivoMatdeDatosButton
        function CreararchivoMatdeDatosButtonPushed(app, event)
            app.data = pyrunfile('ConvertirBases.py', 'pathFinal');
            app.EstoPuedeTardarLabel.Text = "Convercion hecha!";
            datacorregida = char(app.data);
            % Busca todos los archivos
            app.files = dir(datacorregida);
            % Crea un arreglo de datos vacío (como un arreglo de caracteres)
            app.archivos = {};
            % Itera sobre todos los archivos
            for i = 1:length(app.files)
                % Comprueba si el nombre del archivo tiene al menos 3 caracteres
                if length(app.files(i).name) >= 3
                    if strcmp(app.files(i).name(end-2:end), 'mat')
                        app.archivos = [app.archivos; app.files(i).name];
                    end
                end
            end
            % Actualiza los elementos de la lista
            app.BaseDeDatosDropDown.Items = app.archivos;
        end

        % Value changed function: BaseDeDatosDropDown
        function BaseDeDatosDropDownValueChanged(app, event)
      
        end

        % Button pushed function: AgregaralPathButton
        function AgregaralPathButtonPushed(app, event)
            % Obtiene el valor seleccionado en el dropdown
            nombre = app.BaseDeDatosDropDown.Value;
            % Verifica la longitud del nombre
            if length(nombre) == 8
                % La clase es ARR
                clase = 'ARR';
            elseif nombre(1) == 'c'
                % La clase es CHF
                clase = 'CHF';
            else
                % La clase es NSR
                clase = 'NSR';
            end
            % Crea un nuevo registro para la tabla
            registro = {nombre, clase};  % Arreglo de celdas
            registro = cell2table(registro);  % Convertir a tabla
            % Comprueba si el nombre ya existe en la lista
            if ~ismember(nombre, app.nombres)
                % El nombre no existe, agregalo a la lista
                app.nombres = [app.nombres, nombre];
                app.indice = app.indice + 1;
                indiceTemp = num2str(app.indice);
                app.indiceDrop = [app.indiceDrop, cellstr(indiceTemp)];  % Convierte a arreglo de celdas de caracteres
                app.ElegirPathaeliminarDropDown.Items = app.indiceDrop;
                % Agrega el registro a la tabla
                app.UITable.Data = [app.UITable.Data; registro];
            end
        end

        % Drop down opening function: ElegirPathaeliminarDropDown
        function ElegirPathaeliminarDropDownOpening(app, event)
            
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            % Obtiene el valor seleccionado en el dropdown
            DatoEliminar = app.ElegirPathaeliminarDropDown.Value;

            % Convierte DatoEliminar a un número
            DatoEliminar = str2double(DatoEliminar);

            % Elimina el registro de la tabla
            app.UITable.Data(DatoEliminar, :) = [];
            app.indice = app.indice - 1;

            % Elimina el último valor de la matriz app.indiceDrop
            app.indiceDrop = app.indiceDrop(1:end-1);
            app.ElegirPathaeliminarDropDown.Items = app.indiceDrop;
        end

        % Button pushed function: CrearArchivoMatGeneralButton
        function CrearArchivoMatGeneralButtonPushed(app, event)
            app.EstoPuedeTardarLabel.Text = "Ya tenemos tu archivo General!!!";
            % Obtener los nombres de los archivos de la tabla
            nombres_archivos_tabla = app.UITable.Data(:, 1);
            nombres_archivos = table2cell(nombres_archivos_tabla);
        
            % Obtener el número de archivos
            num_archivos = numel(nombres_archivos);
        
            % Inicializar celdas para almacenar datos y etiquetas
            datos = cell(2*num_archivos, 1);
            labels = cell(2*num_archivos, 1);
        
            % Iterar sobre cada nombre de archivo
            for i = 1:num_archivos
                nombre_archivo = nombres_archivos{i}; % Acceder al nombre de archivo en la celda i
        
                % Obtener el tipo del archivo
                if strlength(nombre_archivo) == 8
                    tipo_archivo = 'ARR';
                elseif nombre_archivo(1) == 'c'
                    tipo_archivo = 'CHF';
                else
                    tipo_archivo = 'NSR';
                end
        
                % Cargar los datos del archivo
                archivo = load(nombre_archivo);
                val = archivo.val;
        
                % Dividir los datos en dos partes
                val1 = val(1:1, :);
                val2 = val(2:end, :);
        
                % Reducir los datos a 65536
                val1 = val1(:, 1:65536);
                val2 = val2(:, 1:65536);
        
                % Guardar los datos y las etiquetas
                datos{2*i-1} = val1; % Primer registro
                datos{2*i} = val2;   % Segundo registro
                labels{2*i-1} = {tipo_archivo};
                labels{2*i} = {tipo_archivo};
            end
        
            % Construir la estructura final
            ECGData.Data = vertcat(datos{:});
            ECGData.Labels = vertcat(labels{:});
        
            % Guardar la estructura en un archivo .mat
            save('ECGData.mat', 'ECGData');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ManipulardatosdePhysionetLabel = uilabel(app.UIFigure);
            app.ManipulardatosdePhysionetLabel.Text = 'Manipular datos de Physionet';
            app.Descargar = uibutton(app.UIFigure, 'push');
            app.Descargar.ButtonPushedFcn = createCallbackFcn(app, @DescargarButtonPushed, true);
            app.Descargar.Text = 'Descargar Bases De Datos';
            app.BaseDeDatosDropDownLabel = uilabel(app.UIFigure);
            app.BaseDeDatosDropDownLabel.Text = 'Base De Datos';
            app.BaseDeDatosDropDown = uidropdown(app.UIFigure);
            app.BaseDeDatosDropDown.Items = {};
            app.BaseDeDatosDropDown.ValueChangedFcn = createCallbackFcn(app, @BaseDeDatosDropDownValueChanged, true);
            app.BaseDeDatosDropDown.Value = {};
            app.EstoPuedeTardarLabel = uilabel(app.UIFigure);
            app.EstoPuedeTardarLabel.Text = 'Esto Puede Tardar';
            app.AgregaralPathButton = uibutton(app.UIFigure, 'push');
            app.AgregaralPathButton.ButtonPushedFcn = createCallbackFcn(app, @AgregaralPathButtonPushed, true);
            app.AgregaralPathButton.Text = 'Agregar al Path';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Path'; 'Clase'};
            app.UITable.RowName = {};
            app.ElegirPathaeliminarDropDownLabel = uilabel(app.UIFigure);
            app.ElegirPathaeliminarDropDownLabel.Text = 'Elegir Path a eliminar';
            app.ElegirPathaeliminarDropDown = uidropdown(app.UIFigure);
            app.ElegirPathaeliminarDropDown.Items = {};
            app.ElegirPathaeliminarDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ElegirPathaeliminarDropDownOpening, true);
            app.ElegirPathaeliminarDropDown.Value = {};
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.CrearArchivoMatGeneralButton = uibutton(app.UIFigure, 'push');
            app.CrearArchivoMatGeneralButton.ButtonPushedFcn = createCallbackFcn(app, @CrearArchivoMatGeneralButtonPushed, true);
            app.CrearArchivoMatGeneralButton.Text = 'Crear Archivo .Mat General';
            app.CreararchivoMatdeDatosButton = uibutton(app.UIFigure, 'push');
            app.CreararchivoMatdeDatosButton.ButtonPushedFcn = createCallbackFcn(app, @CreararchivoMatdeDatosButtonPushed, true);
            app.CreararchivoMatdeDatosButton.Text = 'Crear archivo .Mat de Datos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManipularRegistros

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end