classdef Recuperacion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NumeroVerificacion              matlab.ui.control.NumericEditField
        LabelNum                        matlab.ui.control.Label
        VerificarButton                 matlab.ui.control.Button
        NotiCambio                      matlab.ui.control.Label
        CambiarInformacionButton        matlab.ui.control.Button
        ContraseaEditField              matlab.ui.control.EditField
        ContraseaEditFieldLabel         matlab.ui.control.Label
        UsuarioEditField                matlab.ui.control.EditField
        UsuarioEditFieldLabel           matlab.ui.control.Label
        TitleLabel_2                    matlab.ui.control.Label
        NotiCorreo                      matlab.ui.control.Label
        NotiNumero                      matlab.ui.control.Label
        EditFieldNumero                 matlab.ui.control.EditField
        EnviarCorreoDeRecuperacionButton  matlab.ui.control.Button
        EditFieldCorreo                 matlab.ui.control.EditField
        CorreodelUsuarioEditFieldLabel  matlab.ui.control.Label
        TitleLabel                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        LLamadaApp % Interfaz Inicio de Sesion
        numerover % Variable que almacena nuemero de verificacion
        idTipo % identificador de tipo de usuario
        idUsu % identificador de usuario
    end
    
    methods (Access = private)
        %Funcion para crear numeros aleatorios para verificar usuarios
        function numero = generarNumeroAleatorio(~, min, max)
            numero = randi([min max], 1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            movegui(app.UIFigure, 'center')
            app.LLamadaApp = caller;
            app.LLamadaApp.UIFigure.Visible = 'off';  % Ocultar la figura
        end

        % Callback function
        function VerificadorValueChanged(app, event)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.LLamadaApp.UIFigure.Visible = 'on';  % Ocultar la figura
            delete(app)
        end

        % Button pushed function: EnviarCorreoDeRecuperacionButton
        function EnviarCorreoDeRecuperacionButtonPushed(app, event)
            % Generar un número aleatorio entre 1000 y 9999
            numeroAleatorio = generarNumeroAleatorio(app, 1000, 9999);
            % Asignar el número aleatorio al control EditField
            app.NumeroVerificacion.Value = numeroAleatorio;
        
            % Obtener el correo electrónico del control EditField
            correo = get(app.EditFieldCorreo, 'Value');
            % Obtener el número de verificación del control EditField
            app.numerover = get(app.NumeroVerificacion, 'Value');
            
            % Configuración para enviar el correo electrónico
            mail = 'rengerrojo@gmail.com';
            password = 'dsuybsayemcmfpmm';
            server = 'smtp.gmail.com';
        
            % Configuración de preferencias de envío de correo electrónico
            setpref('Internet','E_mail', mail);
            setpref('Internet','SMTP_Server', server);
            setpref('Internet','SMTP_Username',mail);
            setpref('Internet','SMTP_Password',password);
            setpref('Internet','SMTP_Auth', 'true');
        
            % Configuración de propiedades para el envío de correo electrónico
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth','true');
            props.setProperty('mail.smtp.socketFactory.class','javax.net.ssl.SSLSocketFactory');
            props.setProperty('mail.smtp.socketFactory.port','465');
            
            % Conectar a la base de datos
            db = database('bd_Usuarios','root','');
            % Consultar la tabla de información para buscar el correo electrónico
            selectquery = "SELECT * FROM informacion WHERE email = '" + app.EditFieldCorreo.Value + "'";
            resultados = select(db,selectquery);
        
            % Verificar si la consulta devolvió resultados
            if ~isempty(resultados)
                % Enviar un correo electrónico con el número de verificación
                sendmail(correo,'Mensaje de Recuperacion',"Este es el numero de Verificacion: '" + num2str(app.numerover) + "'");
                % Mostrar mensaje de notificación de envío exitoso
                app.NotiCorreo.Text = "Mensaje Correctamente enviado";
                app.NotiCorreo.Position = [232 417 179 22];
                % Mostrar controles relacionados con la verificación del número
                app.EditFieldNumero.Visible = "on";
                app.VerificarButton.Visible = "on";
                app.LabelNum.Visible = "on";
            else
                % Mostrar mensaje de notificación de cuenta inexistente
                app.NotiCorreo.Text = "No existe la cuenta";
                app.NotiCorreo.Position = [232 417 179 22];
            end
        end

        % Button pushed function: VerificarButton
        function VerificarButtonPushed(app, event)
            % Obtener el número de verificación ingresado por el usuario
            NumComp = get(app.EditFieldNumero, 'Value');
            
            % Verificar si el número ingresado coincide con el número de verificación generado previamente
            if num2str(app.numerover) == NumComp 
                % Mostrar mensaje de notificación de número de verificación correcto
                app.NotiNumero.Text = "Numero de Verificacion Correcto";
                app.NotiNumero.Position = [227 212 186 22];
                
                % Mostrar controles para cambiar información de usuario
                app.TitleLabel_2.Visible = "on";
                app.UsuarioEditField.Visible = "on";
                app.UsuarioEditFieldLabel.Visible = "on";
                app.ContraseaEditField.Visible = "on";
                app.ContraseaEditFieldLabel.Visible = "on";
                app.CambiarInformacionButton.Visible = "on";
        
                % Conectar a la base de datos
                db = database('bd_Usuarios','root','');
                
                % Consultar la tabla de información para obtener los detalles del usuario
                selectquery = "SELECT * FROM informacion WHERE email = '" + app.EditFieldCorreo.Value + "'";
                resultados = select(db,selectquery);
                
                % Obtener información del usuario
                app.idUsu = resultados.idusuario;
                app.idTipo = resultados.idtipo;
                nombre = resultados.nombre_usuario;
        
                % Asignar el nombre del usuario al control EditField
                app.UsuarioEditField.Value = string(nombre);
            else
                % Mostrar mensaje de notificación de número de verificación incorrecto
                app.NotiNumero.Text = "Numero de Verificacion Incorrecto";
                app.NotiNumero.Position = [227 212 186 22];
            end
        end

        % Button pushed function: CambiarInformacionButton
        function CambiarInformacionButtonPushed(app, event)
            % Obtener el nombre de usuario y la contraseña ingresados por el usuario
            usuario = string(app.UsuarioEditField.Value);
            contrasena = string(app.ContraseaEditField.Value);
        
            % Verificar si se han proporcionado tanto el nombre de usuario como la contraseña
            if usuario == "" || contrasena == ""
                % Mostrar mensaje de notificación si la información es insuficiente
                app.NotiCambio.Position = [257 144 131 22];
                app.NotiCambio.Text = "Informacion insuficiente";
            else
                % Conectar a la base de datos
                db = database('bd_Usuarios','root','');
        
                % Crear una matriz de celdas con una fila y cuatro columnas para almacenar la nueva información del usuario
                data = cell(1, 5);
                data{1, 1} = string(app.idUsu);
                data{1, 2} = usuario;
                data{1, 3} = contrasena;
                data{1, 4} = string(app.idTipo);
                data{1, 5} = string(app.EditFieldCorreo.Value);
        
                % Convertir la matriz de celdas a una tabla con nombres de variables especificados
                data = cell2table(data, 'VariableNames', {'idusuario' 'nombre_usuario' 'contrasena' 'idtipo' 'email'});
                
                % Crear un filtro de fila para seleccionar el registro correspondiente al correo electrónico del usuario actual
                rf = rowfilter("email");
                rf = rf.email == string(app.EditFieldCorreo.Value);
                
                % Actualizar la información del usuario en la base de datos
                sqlupdate(db,'informacion',data,rf)
                
                % Mostrar mensaje de notificación de usuario modificado
                app.NotiCambio.Position = [257 144 131 22];
                app.NotiCambio.Text = "Usuario Modificado";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Ingrese el Correo Electronico a Recuperar';
            app.CorreodelUsuarioEditFieldLabel = uilabel(app.UIFigure);
            app.CorreodelUsuarioEditFieldLabel.Text = 'Correo del Usuario';
            app.EditFieldCorreo = uieditfield(app.UIFigure, 'text');
            app.EnviarCorreoDeRecuperacionButton = uibutton(app.UIFigure, 'push');
            app.EnviarCorreoDeRecuperacionButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarCorreoDeRecuperacionButtonPushed, true);
            app.EnviarCorreoDeRecuperacionButton.Text = 'Enviar Correo De Recuperacion';
            app.EditFieldNumero = uieditfield(app.UIFigure, 'text');
            app.EditFieldNumero.Visible = 'off';
            app.NotiNumero = uilabel(app.UIFigure);
            app.NotiNumero.Text = '';
            app.NotiCorreo = uilabel(app.UIFigure);
            app.NotiCorreo.Text = '';
            app.TitleLabel_2 = uilabel(app.UIFigure);
            app.TitleLabel_2.Visible = 'off';
            app.TitleLabel_2.Text = 'Cambie Usuario o Contraseña';
            app.UsuarioEditFieldLabel = uilabel(app.UIFigure);
            app.UsuarioEditFieldLabel.Visible = 'off';
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.UsuarioEditField.Visible = 'off';
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Visible = 'off';
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditField.Visible = 'off';
            app.CambiarInformacionButton = uibutton(app.UIFigure, 'push');
            app.CambiarInformacionButton.ButtonPushedFcn = createCallbackFcn(app, @CambiarInformacionButtonPushed, true);
            app.CambiarInformacionButton.Visible = 'off';
            app.CambiarInformacionButton.Text = 'Cambiar Informacion';
            app.NotiCambio = uilabel(app.UIFigure);
            app.NotiCambio.Text = '';
            app.VerificarButton = uibutton(app.UIFigure, 'push');
            app.VerificarButton.ButtonPushedFcn = createCallbackFcn(app, @VerificarButtonPushed, true);
            app.VerificarButton.Visible = 'off';
            app.VerificarButton.Text = 'Verificar';
            app.LabelNum = uilabel(app.UIFigure);
            app.LabelNum.Visible = 'off';
            app.LabelNum.Text = 'Ingrese el Numero que observa en el Correo';
            app.NumeroVerificacion = uieditfield(app.UIFigure, 'numeric');
            app.NumeroVerificacion.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Recuperacion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end