classdef adminUsuarios < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IDaModificarDropDown       matlab.ui.control.DropDown
        IDaModificarDropDownLabel  matlab.ui.control.Label
        IDaEliminarDropDown        matlab.ui.control.DropDown
        IDaEliminarDropDownLabel   matlab.ui.control.Label
        MostrarUsuariosButton      matlab.ui.control.Button
        ModificarButton            matlab.ui.control.Button
        EliminarButton             matlab.ui.control.Button
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        TitleLabel                 matlab.ui.control.Label
        UITable                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        modificar_Usuarios % App para modificacion de usuarios
    end
    
    methods (Access = private)
        
        function ClearTable(~, table)
            table.Data = [];
        end
        
        function AddRowToTable(~, table, data)
            table.Data = [table.Data; data];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center')
        end

        % Button pushed function: MostrarUsuariosButton
        function MostrarUsuariosButtonPushed(app, event)
            % Conectar a la base de datos
            db = database('bd_Usuarios','root','');
            
            % Consultar la tabla de información
            selectquery = "SELECT * FROM informacion";
            resultados = select(db,selectquery);
        
            % Limpiar la tabla
            ClearTable(app, app.UITable);
        
            % Agregar los datos del select a la tabla
            for i = 1:size(resultados,1)
                % Obtener los IDs de usuario y convertirlos a cadena de texto
                idUsuarios = resultados.idusuario;
                idUsuariosString = string(idUsuarios);
                
                % Dividir el valor del ID de usuario en un arreglo de celdas
                idUsuariosArray = split(idUsuariosString,',');
                
                % Asignar el arreglo de celdas al objeto IDaModificarDropDown
                app.IDaModificarDropDown.Items = idUsuariosArray;
                
                % Asignar el arreglo de celdas al objeto IDaEliminarDropDown
                app.IDaEliminarDropDown.Items = idUsuariosArray;
                
                % Agregar una fila a la tabla para cada resultado
                AddRowToTable(app, app.UITable, resultados(i,:));
            end
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            % Conectar a la base de datos
            db = database('bd_Usuarios','root','');
            
            % Consultar la tabla de información y construir la consulta SQL para eliminar un usuario
            selectquery = "DELETE FROM informacion WHERE idusuario = " + app.IDaEliminarDropDown.Value + ";";
            
            % Ejecutar la consulta SQL para eliminar el usuario de la base de datos
            execute(db,selectquery)
        end

        % Button pushed function: ModificarButton
        function ModificarButtonPushed(app, event)
             % Obtener el ID de usuario seleccionado en el menú desplegable
            id_usuario = app.IDaModificarDropDown.Value;
            
            % Abrir una nueva interfaz para permitir la modificación de información del usuario seleccionado
            app.modificar_Usuarios = modificarUsuario(id_usuario);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'idUsuario'; 'Usuario'; 'Correo'; 'Contrasena'; 'idTipo'};
            app.UITable.RowName = {};
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Aqui podra observar a todos los usuarios registrados';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'En idTipo 1 hace referencia a Administradores'; 'En idTipo 2 hace referencia a Usuarios'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Porfavor eliga el ID del usuario que quiere modificar o eliminar y luego precione el boton';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.ModificarButton = uibutton(app.UIFigure, 'push');
            app.ModificarButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarButtonPushed, true);
            app.ModificarButton.Text = 'Modificar';
            app.MostrarUsuariosButton = uibutton(app.UIFigure, 'push');
            app.MostrarUsuariosButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarUsuariosButtonPushed, true);
            app.MostrarUsuariosButton.Text = 'Mostrar Usuarios';
            app.IDaEliminarDropDownLabel = uilabel(app.UIFigure);
            app.IDaEliminarDropDownLabel.Text = 'ID a Eliminar';
            app.IDaEliminarDropDown = uidropdown(app.UIFigure);
            app.IDaEliminarDropDown.Items = {};
            app.IDaEliminarDropDown.Value = {};
            app.IDaModificarDropDownLabel = uilabel(app.UIFigure);
            app.IDaModificarDropDownLabel.Text = 'ID a Modificar';
            app.IDaModificarDropDown = uidropdown(app.UIFigure);
            app.IDaModificarDropDown.Items = {};
            app.IDaModificarDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adminUsuarios

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end