classdef crearUsuarios < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CorreoEditField             matlab.ui.control.EditField
        CorreoEditFieldLabel        matlab.ui.control.Label
        TipodeUsuarioDropDown       matlab.ui.control.DropDown
        TipodeUsuarioDropDownLabel  matlab.ui.control.Label
        ContraseaEditField          matlab.ui.control.EditField
        ContraseaEditFieldLabel     matlab.ui.control.Label
        UsuarioEditField            matlab.ui.control.EditField
        UsuarioEditFieldLabel       matlab.ui.control.Label
        mensajeLabel                matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        CrearUsuarioButton          matlab.ui.control.Button
        PorFavorIngreselaSiguienteInformacionLabel  matlab.ui.control.Label
        TitleLabel                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center')
        end

        % Button pushed function: CrearUsuarioButton
        function CrearUsuarioButtonPushed(app, event)
            % Obtener los valores de los campos de entrada de la interfaz de usuario
            email = string(app.CorreoEditField.Value);
            usuario = string(app.UsuarioEditField.Value);
            contrasena = string(app.ContraseaEditField.Value);
            idTipo = string(app.TipodeUsuarioDropDown.Value);
        
            % Verificar si los campos de usuario, contraseña o correo electrónico están vacíos
            if usuario == "" || contrasena == "" || email == ""
                % Mostrar un mensaje de advertencia si la información es insuficiente
                app.mensajeLabel.Position = [185 69 200 22];
                app.mensajeLabel.Text = "Informacion insuficiente";
            else
                % Conectar a la base de datos
                db = database('bd_Usuarios','root','');
        
                % Crear una matriz de celdas con una fila y cinco columnas para almacenar la información del nuevo usuario
                data = cell(1, 5);
                data{1, 2} = usuario; % Nombre de usuario
                data{1, 3} = contrasena; % Contraseña
                data{1, 4} = idTipo; % ID del tipo de usuario
                data{1, 5} = email; % Correo electrónico
        
                % Convertir la cadena de ID del tipo de usuario a un número
                data{1, 4} = str2double(data{1, 4});
        
                % Convertir la matriz de celdas en una tabla con nombres de variables específicos
                data = cell2table(data, 'VariableNames', {'idusuario' 'nombre_usuario' 'contrasena' 'idtipo' 'email'});
        
                % Insertar la información del nuevo usuario en la base de datos
                sqlwrite(db, 'informacion', data)
        
                % Mostrar un mensaje de confirmación después de crear el usuario
                app.mensajeLabel.Position = [185 69 200 22];
                app.mensajeLabel.Text = "Usuario Creado";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Bienvenido a la creacion de Usuarios';
            app.PorFavorIngreselaSiguienteInformacionLabel = uilabel(app.UIFigure);
            app.PorFavorIngreselaSiguienteInformacionLabel.Text = 'Por Favor Ingrese la Siguiente Informacion';
            app.CrearUsuarioButton = uibutton(app.UIFigure, 'push');
            app.CrearUsuarioButton.ButtonPushedFcn = createCallbackFcn(app, @CrearUsuarioButtonPushed, true);
            app.CrearUsuarioButton.Text = 'Crear Usuario';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'En Tipo de Usuario 1 hace referencia a Administradores'; 'En Tipo de Usuario 2 hace referencia a Usuarios'};
            app.mensajeLabel = uilabel(app.UIFigure);
            app.mensajeLabel.Text = '';
            app.UsuarioEditFieldLabel = uilabel(app.UIFigure);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.TipodeUsuarioDropDownLabel = uilabel(app.UIFigure);
            app.TipodeUsuarioDropDownLabel.Text = 'Tipo de Usuario';
            app.TipodeUsuarioDropDown = uidropdown(app.UIFigure);
            app.TipodeUsuarioDropDown.Items = {'1', '2'};
            app.TipodeUsuarioDropDown.Value = '1';
            app.CorreoEditFieldLabel = uilabel(app.UIFigure);
            app.CorreoEditFieldLabel.Text = 'Correo ';
            app.CorreoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = crearUsuarios

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end