classdef interfaceDatosS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ActializarElementosButton       matlab.ui.control.Button
        CantidaddeBachEditField         matlab.ui.control.NumericEditField
        CantidaddeBachEditFieldLabel    matlab.ui.control.Label
        CantidaddeEpocasEditField       matlab.ui.control.NumericEditField
        CantidaddeEpocasEditFieldLabel  matlab.ui.control.Label
        TasadeAprendizajeInicialEditField  matlab.ui.control.NumericEditField
        TasadeAprendizajeInicialEditFieldLabel  matlab.ui.control.Label
        CambiarHiperParametrosdeSqueezeNetLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        IntRedS % Interfaz de la red neuronal Squeeze
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, mxepch, batchSize, learnRate)
            app.IntRedS = caller;

            app.TasadeAprendizajeInicialEditField.Value = learnRate;
            app.CantidaddeEpocasEditField.Value = mxepch;
            app.CantidaddeBachEditField.Value = batchSize;
            movegui(app.UIFigure, 'center')
        end

        % Button pushed function: ActializarElementosButton
        function ActializarElementosButtonPushed(app, event)
            ActualizarElementos(app.IntRedS, app.CantidaddeEpocasEditField.Value, app.CantidaddeBachEditField.Value, app.TasadeAprendizajeInicialEditField.Value);

            delete(app)  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CambiarHiperParametrosdeSqueezeNetLabel = uilabel(app.UIFigure);
            app.CambiarHiperParametrosdeSqueezeNetLabel.Text = 'Cambiar HiperParametros de Squeeze Net';
            app.TasadeAprendizajeInicialEditFieldLabel = uilabel(app.UIFigure);
            app.TasadeAprendizajeInicialEditFieldLabel.Text = 'Tasa de Aprendizaje Inicial';
            app.TasadeAprendizajeInicialEditField = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddeEpocasEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddeEpocasEditFieldLabel.Text = 'Cantidad de Epocas';
            app.CantidaddeEpocasEditField = uieditfield(app.UIFigure, 'numeric');
            app.CantidaddeBachEditFieldLabel = uilabel(app.UIFigure);
            app.CantidaddeBachEditFieldLabel.Text = 'Cantidad de Bach';
            app.CantidaddeBachEditField = uieditfield(app.UIFigure, 'numeric');
            app.ActializarElementosButton = uibutton(app.UIFigure, 'push');
            app.ActializarElementosButton.ButtonPushedFcn = createCallbackFcn(app, @ActializarElementosButtonPushed, true);
            app.ActializarElementosButton.Text = 'Actializar Elementos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaceDatosS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end