classdef interfazIndex < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        AdministraciondeECGsMenu  matlab.ui.container.Menu
        ExtraccinyalmacenamientodedatosMenu  matlab.ui.container.Menu
        AdminitrarusuariosMenu    matlab.ui.container.Menu
        AdministrarMenu           matlab.ui.container.Menu
        CrearUsuariosMenu         matlab.ui.container.Menu
        ManipulaciondeRedesNeuronalesMenu  matlab.ui.container.Menu
        GoogLeNetMenu             matlab.ui.container.Menu
        SqueezeNetMenu            matlab.ui.container.Menu
        PrimeramentedesplegamoselmenudeManipulaciondeECGLabel  matlab.ui.control.Label
        InstruccionesLabel        matlab.ui.control.Label
        SaludoLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % App principal
        AdministrasAPP %App de administracion de usuarios
        CrearAPP % App de Creacion de usuarios
        idTipo % Variable para identificar usuarios
        ManipularRedGooApp %App para manipular red neuronal de GoogLe
        ManipularRedSqueApp %App para manipular red neuronal de SqueezeNet
        ManipularDatos %App para manipular datos de PhysioNet
        Nombre %Variable para almacenar el usuario
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, nombre, idtipo)
            % Establecer la aplicación que llamó a esta aplicación
            app.CallingApp = caller;
            
            % Mover la figura al centro de la pantalla
            movegui(app.UIFigure, 'center')
            
            % Ocultar la figura de la aplicación que llamó a esta aplicación
            app.CallingApp.UIFigure.Visible = 'off';
            
            % Configurar la posición del objeto de etiqueta SaludoLabel
            app.SaludoLabel.Position = [17,470,800,63];
            
            % Asignar el tipo de usuario y el nombre
            app.idTipo = idtipo;
            app.Nombre = nombre;
            
            % Configurar el saludo en función del tipo de usuario
            if idtipo == 1
                app.SaludoLabel.Text = "Hola Admin: " + nombre;
            elseif idtipo == 2
                app.SaludoLabel.Text = "Hola Usuario: " + nombre;
                
                % Ocultar menús específicos para usuarios normales
                app.AdminitrarusuariosMenu.Visible = "off";
                app.AdministraciondeECGsMenu.Visible = "off";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.UIFigure.Visible = 'on';  % Ocultar la figura
            delete(app)
        end

        % Menu selected function: AdministrarMenu
        function AdministrarMenuSelected(app, event)
            app.AdministrasAPP = adminUsuarios();
        end

        % Menu selected function: CrearUsuariosMenu
        function CrearUsuariosMenuSelected(app, event)
            app.CrearAPP = crearUsuarios();
        end

        % Menu selected function: GoogLeNetMenu
        function GoogLeNetMenuSelected(app, event)
            app.ManipularRedGooApp = ManipularRedGApp(app.idTipo, app.Nombre);
        end

        % Menu selected function: SqueezeNetMenu
        function SqueezeNetMenuSelected(app, event)
            app.ManipularRedSqueApp = ManipularRedSApp(app.idTipo, app.Nombre);
        end

        % Menu selected function: ExtraccinyalmacenamientodedatosMenu
        function ExtraccinyalmacenamientodedatosMenuSelected(app, event)
            app.ManipularDatos = ManipularRegistros();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AdministraciondeECGsMenu = uimenu(app.UIFigure);
            app.AdministraciondeECGsMenu.Text = 'Administracion de ECGs';
            app.ExtraccinyalmacenamientodedatosMenu = uimenu(app.AdministraciondeECGsMenu);
            app.ExtraccinyalmacenamientodedatosMenu.MenuSelectedFcn = createCallbackFcn(app, @ExtraccinyalmacenamientodedatosMenuSelected, true);
            app.ExtraccinyalmacenamientodedatosMenu.Text = 'Extracción y almacenamiento de datos';
            app.AdminitrarusuariosMenu = uimenu(app.UIFigure);
            app.AdminitrarusuariosMenu.Text = 'Adminitrar usuarios';
            app.AdministrarMenu = uimenu(app.AdminitrarusuariosMenu);
            app.AdministrarMenu.MenuSelectedFcn = createCallbackFcn(app, @AdministrarMenuSelected, true);
            app.AdministrarMenu.Text = 'Administrar';
            app.CrearUsuariosMenu = uimenu(app.AdminitrarusuariosMenu);
            app.CrearUsuariosMenu.MenuSelectedFcn = createCallbackFcn(app, @CrearUsuariosMenuSelected, true);
            app.CrearUsuariosMenu.Text = 'Crear Usuarios';
            app.ManipulaciondeRedesNeuronalesMenu = uimenu(app.UIFigure);
            app.ManipulaciondeRedesNeuronalesMenu.Text = 'Manipulacion de Redes Neuronales';
            app.GoogLeNetMenu = uimenu(app.ManipulaciondeRedesNeuronalesMenu);
            app.GoogLeNetMenu.MenuSelectedFcn = createCallbackFcn(app, @GoogLeNetMenuSelected, true);
            app.GoogLeNetMenu.Text = 'GoogLe Net';
            app.SqueezeNetMenu = uimenu(app.ManipulaciondeRedesNeuronalesMenu);
            app.SqueezeNetMenu.MenuSelectedFcn = createCallbackFcn(app, @SqueezeNetMenuSelected, true);
            app.SqueezeNetMenu.Text = 'SqueezeNet';
            app.SaludoLabel = uilabel(app.UIFigure);
            app.SaludoLabel.Text = '';
            app.InstruccionesLabel = uilabel(app.UIFigure);
            app.InstruccionesLabel.Text = 'Instrucciones';
            app.PrimeramentedesplegamoselmenudeManipulaciondeECGLabel = uilabel(app.UIFigure);
            app.PrimeramentedesplegamoselmenudeManipulaciondeECGLabel.Text = {'1.-Primeramente, buscamos nuestro archivo utilizando el botón "Buscar Archivo". Verificamos que sea ECGData.mat. Si usted no cuenta con este archivo, al final de estas instrucciones se dejará un enlace a un repositorio en el cual podrá descargarlo.'; '2.-Importamos los datos usando el botón "Importar Archivo". Opcionalmente, si usted quiere verificar la presencia de los datos, puede dar clic en el botón "Observar Datos".'; '3.-Creamos los directorios en su carpeta temporal con el botón "Crear Directorios". En Windows, la manera para encontrarlos es usando la siguiente dirección: "C:\Users\SuUsuario\AppData\Local\Temp".'; '4.-En este punto, los 2 botones faltantes son los que más tiempo tomarán dependiendo del equipo que lo ejecute. Las redes neuronales son un tipo especial de imágenes llamadas escalógramas, por lo tanto daremos clic en el botón "Creación de Escalogramas y división de imágenes".'; '5.-Por último, utilizaremos el botón "Crear y Entrenar Red Neuronal" y esperaremos a que termine el entrenamiento.'; '6.-Desplegaremos el menú de imágenes, en el cual elegiremos alguna imagen para su validación y predicción dando clic en el botón "Predecir Imagen" una vez la hayamos seleccionado.'; ''; ''; ''; 'Link para obtener archivo ECGData: https://github.com/Incog89/Tesis/tree/main'; 'Instrucciones para Descargarlo'; '1.- Acceder al link anterior, dar clic en Code y por ultimo clic en "Download ZIP".'; '2.- Extraer unicamente la carpeta llamada "physionet_ECG_data-main"'; '3.- Presionar la combinacion de teclas "Windows+R", y escribir "%temp%'; '4.- Pegar la carpeta anteriormente extraida.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfazIndex(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end