classdef modificarUsuario < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CorreoEditField             matlab.ui.control.EditField
        CorreoEditFieldLabel        matlab.ui.control.Label
        TipodeUsuarioDropDown       matlab.ui.control.DropDown
        TipodeUsuarioDropDownLabel  matlab.ui.control.Label
        ContraseaEditField          matlab.ui.control.EditField
        ContraseaEditFieldLabel     matlab.ui.control.Label
        UsuarioEditField            matlab.ui.control.EditField
        UsuarioEditFieldLabel       matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        mensajeLabel                matlab.ui.control.Label
        ModificarInformacionButton  matlab.ui.control.Button
        PorFavorIngreselaNuevaInformacionLabel  matlab.ui.control.Label
        TitleLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        idUsu % Identificador de usuario
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, idUsu)
            % Establecer el ID de usuario recibido como argumento
            app.idUsu = idUsu;
            
            % Mover la interfaz de la aplicación al centro de la pantalla
            movegui(app.UIFigure, 'center')
            
            % Conectar a la base de datos
            db = database('bd_Usuarios','root','');
            
            % Consultar la tabla de información para obtener los detalles del usuario con el ID proporcionado
            selectquery = "SELECT * FROM informacion WHERE idusuario = '" + app.idUsu + "'";
            resultados = select(db, selectquery);
            
            % Extraer la información del usuario desde los resultados de la consulta
            nombre = resultados.nombre_usuario;
            contra = resultados.contrasena;
            email = resultados.email;
        
            % Asignar la información del usuario a los campos de edición correspondientes en la interfaz de la aplicación
            app.UsuarioEditField.Value = string(nombre);
            app.ContraseaEditField.Value = string(contra);
            app.CorreoEditField.Value = string(email);
        end

        % Button pushed function: ModificarInformacionButton
        function ModificarInformacionButtonPushed(app, event)
             % Obtener los valores de los campos de edición de la interfaz de usuario
            usuario = string(app.UsuarioEditField.Value);
            contrasena = string(app.ContraseaEditField.Value);
            email = string(app.CorreoEditField.Value);
            idTipo = string(app.TipodeUsuarioDropDown.Value);
        
            % Verificar si los campos de usuario y contraseña están vacíos
            if usuario == "" || contrasena == ""
                % Mostrar un mensaje de advertencia si los campos están vacíos
                app.mensajeLabel.Position = [185 69 200 22];
                app.mensajeLabel.Text = "Informacion insuficiente";
            else
                % Conectar a la base de datos
                db = database('bd_Usuarios','root','');
        
                % Crear una matriz de celdas con una fila y cinco columnas para almacenar la información del usuario
                data = cell(1, 5);
                data{1, 1} = string(app.idUsu); % ID de usuario
                data{1, 2} = usuario; % Nombre de usuario
                data{1, 3} = contrasena; % Contraseña
                data{1, 4} = idTipo; % ID del tipo de usuario
                data{1, 5} = email; % Correo electrónico
        
                % Convertir la matriz de celdas en una tabla con nombres de variables específicos
                data = cell2table(data, 'VariableNames', {'idusuario' 'nombre_usuario' 'contrasena' 'idtipo' 'email'});
        
                % Definir el filtro de fila para identificar la fila correspondiente al usuario actual
                rf = rowfilter("idusuario");
                rf = rf.idusuario == app.idUsu;
        
                % Actualizar la información del usuario en la base de datos
                sqlupdate(db, 'informacion', data, rf)
        
                % Mostrar un mensaje de confirmación después de la modificación exitosa
                app.mensajeLabel.Position = [185 55 200 22];
                app.mensajeLabel.Text = "Usuario Modificado";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'Bienvenido a la Modificacion de Usuarios';
            app.PorFavorIngreselaNuevaInformacionLabel = uilabel(app.UIFigure);
            app.PorFavorIngreselaNuevaInformacionLabel.Text = 'Por Favor Ingrese la Nueva Informacion';
            app.ModificarInformacionButton = uibutton(app.UIFigure, 'push');
            app.ModificarInformacionButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarInformacionButtonPushed, true);
            app.ModificarInformacionButton.Text = 'Modificar Informacion';
            app.mensajeLabel = uilabel(app.UIFigure);
            app.mensajeLabel.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'En Tipo de Usuario 1 hace referencia a Administradores'; 'En Tipo de Usuario 2 hace referencia a Usuarios'};
            app.UsuarioEditFieldLabel = uilabel(app.UIFigure);
            app.UsuarioEditFieldLabel.Text = 'Usuario';
            app.UsuarioEditField = uieditfield(app.UIFigure, 'text');
            app.ContraseaEditFieldLabel = uilabel(app.UIFigure);
            app.ContraseaEditFieldLabel.Text = 'Contraseña';
            app.ContraseaEditField = uieditfield(app.UIFigure, 'text');
            app.TipodeUsuarioDropDownLabel = uilabel(app.UIFigure);
            app.TipodeUsuarioDropDownLabel.Text = 'Tipo de Usuario';
            app.TipodeUsuarioDropDown = uidropdown(app.UIFigure);
            app.TipodeUsuarioDropDown.Items = {'1', '2'};
            app.TipodeUsuarioDropDown.Value = '1';
            app.CorreoEditFieldLabel = uilabel(app.UIFigure);
            app.CorreoEditFieldLabel.Text = 'Correo';
            app.CorreoEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modificarUsuario(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end